classdef RandomPulseGeneration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RepetitiontimeofoneparticularpulsesequenceT2sEditField  matlab.ui.control.NumericEditField
        RepetitiontimeofoneparticularpulsesequenceT2sEditFieldLabel  matlab.ui.control.Label
        StopPulsesButton             matlab.ui.control.Button
        GeneratePulsesButton         matlab.ui.control.Button
        DisconnectAWGButton          matlab.ui.control.Button
        ConnectAWGButton             matlab.ui.control.Button
        DifferentPulseRealizationforonebasisN1Spinner  matlab.ui.control.Spinner
        DifferentPulseRealizationforonebasisN1SpinnerLabel  matlab.ui.control.Label
        FinalFourierBasisNumbermSpinner  matlab.ui.control.Spinner
        FinalFourierBasisNumbermSpinnerLabel  matlab.ui.control.Label
        RandomPulseGenerationLabel   matlab.ui.control.Label
        NumberofPulsesMSpinner       matlab.ui.control.Spinner
        NumberofPulsesMSpinnerLabel  matlab.ui.control.Label
        FrameDropDown                matlab.ui.control.DropDown
        FrameDropDownLabel           matlab.ui.control.Label
        FrequencyofthesinusoidalpulseGHzEditField  matlab.ui.control.NumericEditField
        FrequencyofthesinusoidalpulseGHzEditFieldLabel  matlab.ui.control.Label
        WaittimeafterinitializationpulsensEditField  matlab.ui.control.NumericEditField
        WaittimeafterinitializationpulsensEditFieldLabel  matlab.ui.control.Label
        FreeevolutiontimetaunsEditField  matlab.ui.control.NumericEditField
        FreeevolutiontimetaunsEditFieldLabel  matlab.ui.control.Label
        piPulseDurationnsEditField   matlab.ui.control.NumericEditField
        piPulseDurationnsEditFieldLabel  matlab.ui.control.Label
        InitializationPulseDurationnsEditField  matlab.ui.control.NumericEditField
        InitializationPulseDurationnsEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        AWG   % handle to AWG object
        stopRequested logical = false;
        SegmentVec % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectAWGButton
        function ConnectAWGButtonPushed(app, event)
            app.AWG = ClassAWG.getInstance();
            app.AWG.connect(); 
        end

        % Button pushed function: DisconnectAWGButton
        function DisconnectAWGButtonPushed(app, event)
            app.AWG.CloseConnection();
        end

        % Button pushed function: GeneratePulsesButton
        function GeneratePulsesButtonPushed(app, event)

            app.stopRequested = false;
            M = app.NumberofPulsesMSpinner.Value;
            init_duration = app.InitializationPulseDurationnsEditField.Value;
            pi_duration = app.piPulseDurationnsEditField.Value;
            tau = app.FreeevolutiontimetaunsEditField.Value;
            tau_initial = app.WaittimeafterinitializationpulsensEditField.Value;
            frequency = app.FrequencyofthesinusoidalpulseGHzEditField.Value;
            frame = app.FrameDropDown.Value;
            m = app.FinalFourierBasisNumbermSpinner.Value;
            N1 = app.DifferentPulseRealizationforonebasisN1Spinner.Value;
            T2 = app.RepetitiontimeofoneparticularpulsesequenceT2sEditField.Value;


            final_time = init_duration + tau_initial + ...    % delay after init
                pi_duration/2 + ...                           % first pi/2
                tau/2 + ...                                   % initial free delay
                M * pi_duration + ...                         % M pulses
                (M-1) * tau + ...                             % (M-1) inter_pulse delays
                tau/2 + ...                                   % final free delay
                pi_duration/2 + ...                           % final pi/2
                tau/2;                                        % post delay
            
            fprintf('Creating initialization pulse sequence...\n');
            SignalVec_Init = CreateInitializationPulses(app.AWG, init_duration, final_time);
            pause(0.2);
            
            app.SegmentVec = {};
            for i = 1:m
                fprintf('\n>> Entering Fourier basis #%d\n', i);
                fprintf('Uploading %d random pulse sequences for basis #%d\n', N1, i);
                for j = 1:N1
                    drawnow;
                    if app.stopRequested
                        fprintf('Stopping requested by user. Exiting pulse generation...\n');
                        return;  
                    end
                    
                    SignalVec_Random = CreateRandomPulses(app.AWG, M, i, init_duration, pi_duration, tau, tau_initial, frequency, frame);
                    Segment = cell(1, 2);
                    Segment{1} = SignalVec_Init;
                    Segment{2} = SignalVec_Random;
                    
                    app.AWG.UploadSegment(Segment);
                    app.SegmentVec{end+1} = Segment;
                    pause(0.05);
                end
            end
                
            fprintf('>> Finished Uploading all sequences for Fourier basis %d\n', i);
           
            for i = 1:m
                fprintf('\n>> Executing Fourier basis #%d\n', i);
                for j = 1:N1
                    if app.stopRequested
                        fprintf('Stopping requested by user. Exiting segment run...\n');
                        return;
                    end

                    idx = (i-1) * N1 +j;
                    fprintf('>> Running sequence #%d of for Fourier basis #%d for %.2f seconds...\n', j, i, T2);
                    startTime = tic;
                    while toc(startTime) < T2
                        app.AWG.RunSegment(app.SegmentVec{idx});
                        pause(0.01);
                    end
                    fprintf('>> Completed running\n');
                    pause(0.5);
                end
            end
        end

        % Button pushed function: StopPulsesButton
        function StopPulsesButtonPushed(app, event)
            app.stopRequested = true;
            disp("Pulse Stopped");
            app.AWG.stopAWG()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitializationPulseDurationnsEditFieldLabel = uilabel(app.UIFigure);
            app.InitializationPulseDurationnsEditFieldLabel.Text = 'Initialization Pulse Duration (ns)';
            app.InitializationPulseDurationnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitializationPulseDurationnsEditField.Limits = [0 Inf];
            app.piPulseDurationnsEditFieldLabel = uilabel(app.UIFigure);
            app.piPulseDurationnsEditFieldLabel.Text = 'pi-Pulse Duration (ns)';
            app.piPulseDurationnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.piPulseDurationnsEditField.Limits = [0 Inf];
            app.FreeevolutiontimetaunsEditFieldLabel = uilabel(app.UIFigure);
            app.FreeevolutiontimetaunsEditFieldLabel.Text = 'Free evolution time, tau (ns)';
            app.FreeevolutiontimetaunsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreeevolutiontimetaunsEditField.Limits = [0 Inf];
            app.WaittimeafterinitializationpulsensEditFieldLabel = uilabel(app.UIFigure);
            app.WaittimeafterinitializationpulsensEditFieldLabel.Text = 'Wait time after initialization pulse (ns)';
            app.WaittimeafterinitializationpulsensEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaittimeafterinitializationpulsensEditField.Limits = [0 Inf];
            app.FrequencyofthesinusoidalpulseGHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyofthesinusoidalpulseGHzEditFieldLabel.Text = 'Frequency of the sinusoidal pulse (GHz)';
            app.FrequencyofthesinusoidalpulseGHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyofthesinusoidalpulseGHzEditField.Limits = [0 Inf];
            app.FrameDropDownLabel = uilabel(app.UIFigure);
            app.FrameDropDownLabel.Text = 'Frame';
            app.FrameDropDown = uidropdown(app.UIFigure);
            app.FrameDropDown.Items = {'lab', 'rotating'};
            app.FrameDropDown.Value = 'lab';
            app.NumberofPulsesMSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofPulsesMSpinnerLabel.Text = 'Number of Pulses (M)';
            app.NumberofPulsesMSpinner = uispinner(app.UIFigure);
            app.NumberofPulsesMSpinner.Limits = [0 Inf];
            app.RandomPulseGenerationLabel = uilabel(app.UIFigure);
            app.RandomPulseGenerationLabel.Text = 'Random Pulse Generation';
            app.FinalFourierBasisNumbermSpinnerLabel = uilabel(app.UIFigure);
            app.FinalFourierBasisNumbermSpinnerLabel.Text = 'Final Fourier Basis Number (m)';
            app.FinalFourierBasisNumbermSpinner = uispinner(app.UIFigure);
            app.FinalFourierBasisNumbermSpinner.Limits = [0 Inf];
            app.DifferentPulseRealizationforonebasisN1SpinnerLabel = uilabel(app.UIFigure);
            app.DifferentPulseRealizationforonebasisN1SpinnerLabel.Text = 'Different Pulse Realization for one basis (N1)';
            app.DifferentPulseRealizationforonebasisN1Spinner = uispinner(app.UIFigure);
            app.DifferentPulseRealizationforonebasisN1Spinner.Limits = [0 Inf];
            app.ConnectAWGButton = uibutton(app.UIFigure, 'push');
            app.ConnectAWGButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectAWGButtonPushed, true);
            app.ConnectAWGButton.Text = 'Connect AWG';
            app.DisconnectAWGButton = uibutton(app.UIFigure, 'push');
            app.DisconnectAWGButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectAWGButtonPushed, true);
            app.DisconnectAWGButton.Text = 'Disconnect AWG';
            app.GeneratePulsesButton = uibutton(app.UIFigure, 'push');
            app.GeneratePulsesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePulsesButtonPushed, true);
            app.GeneratePulsesButton.Text = 'Generate Pulses';
            app.StopPulsesButton = uibutton(app.UIFigure, 'push');
            app.StopPulsesButton.ButtonPushedFcn = createCallbackFcn(app, @StopPulsesButtonPushed, true);
            app.StopPulsesButton.Text = 'Stop Pulses';
            app.RepetitiontimeofoneparticularpulsesequenceT2sEditFieldLabel = uilabel(app.UIFigure);
            app.RepetitiontimeofoneparticularpulsesequenceT2sEditFieldLabel.Text = 'Repetition time of one particular pulse sequence (T2) (s)';
            app.RepetitiontimeofoneparticularpulsesequenceT2sEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RandomPulseGeneration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end